classdef FinalPushinProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PLAYButton      matlab.ui.control.Button
        shuffleGIF      matlab.ui.control.Image
        Image7          matlab.ui.control.Image
        player1_Card2   matlab.ui.control.Image
        player1_Card1   matlab.ui.control.Image
        Image6_4        matlab.ui.control.Image
        Image6_3        matlab.ui.control.Image
        Image6_2        matlab.ui.control.Image
        Image6          matlab.ui.control.Image
        communityCard5  matlab.ui.control.Image
        communityCard4  matlab.ui.control.Image
        communityCard3  matlab.ui.control.Image
        communityCard2  matlab.ui.control.Image
        communityCard1  matlab.ui.control.Image
        twentyChip      matlab.ui.control.Image
        hundoChip       matlab.ui.control.Image
        fityChip        matlab.ui.control.Image
        BetButton       matlab.ui.control.Button
        FoldButton      matlab.ui.control.Button
        CheckButton     matlab.ui.control.Button
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FoldButton
        function FoldButtonPushed(app, event)
            app.FoldButton.Enable = 'off';
            app.CheckButton.Enable = 'off';
            app.BetButton.Enable = 'off';

            load laughter
            plot(y);
            sound=audioplayer(y,Fs);
            play(sound);
        end

        % Button pushed function: BetButton
        function BetButtonPushed(app, event)
            app.FoldButton.Visible = 'off';
            app.CheckButton.Visible = 'off';
            app.BetButton.Visible = 'off';

            app.twentyChip.Visible = 'on';
            app.fityChip.Visible = 'on';
            app.hundoChip.Visible = 'on';

            app.twentyChip.Enable = 'on';
            app.fityChip.Enable = 'on';
            app.hundoChip.Enable = 'on';
        end

        % Image clicked function: hundoChip
        function hundoChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';
        end

        % Image clicked function: fityChip
        function fityChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';
        end

        % Image clicked function: twentyChip
        function twentyChipImageClicked(app, event)
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';

            app.twentyChip.Visible = 'off';
            app.fityChip.Visible = 'off';
            app.hundoChip.Visible = 'off';

            app.twentyChip.Enable = 'off';
            app.fityChip.Enable = 'off';
            app.hundoChip.Enable = 'off';
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
             app.PLAYButton.Visible = 'off';
            
            for i = 10 % loop through the struct
                app.shuffleGIF.Visible = 'on';%  shows image
                app.shuffleGIF.Enable = 'on';
                pause(2); % waits for 10 secs
                app.shuffleGIF.Visible = 'off';
                app.shuffleGIF.Enable = 'off';
            end
            
            app.Image7.Visible = 'off';
            app.FoldButton.Visible = 'on';
            app.CheckButton.Visible = 'on';
            app.BetButton.Visible = 'on';
            app.Image.Visible = 'on';
            pause(0.1);
            app.Image6.Visible = 'on';
            pause(0.1);
            app.Image6_2.Visible = 'on';
            pause(0.1);
            app.communityCard1.Visible = 'on';
            pause(0.1);
            app.communityCard2.Visible = 'on';
            pause(0.1);
            app.communityCard3.Visible = 'on';
            pause(0.1);
            app.communityCard4.Visible = 'on';
            pause(0.1);
            app.communityCard5.Visible = 'on';
            pause(0.1);
            app.Image6_4.Visible = 'on';
            pause(0.1);
            app.Image6_3.Visible = 'on';
            pause(0.1);
            app.player1_Card2.Visible = 'on';
            pause(0.1);
            app.player1_Card1.Visible = 'on';
            

            app.BetButton.Enable = 'on';
            app.FoldButton.Enable = 'on';
            app.CheckButton.Enable = 'on';
            app.PLAYButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'PokerTable.png';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.Visible = 'off';
            app.CheckButton.Text = 'Check';
            app.FoldButton = uibutton(app.UIFigure, 'push');
            app.FoldButton.ButtonPushedFcn = createCallbackFcn(app, @FoldButtonPushed, true);
            app.FoldButton.Enable = 'off';
            app.FoldButton.Visible = 'off';
            app.FoldButton.Text = 'Fold';
            app.BetButton = uibutton(app.UIFigure, 'push');
            app.BetButton.ButtonPushedFcn = createCallbackFcn(app, @BetButtonPushed, true);
            app.BetButton.Enable = 'off';
            app.BetButton.Visible = 'off';
            app.BetButton.Text = 'Bet';
            app.fityChip = uiimage(app.UIFigure);
            app.fityChip.ImageClickedFcn = createCallbackFcn(app, @fityChipImageClicked, true);
            app.fityChip.Enable = 'off';
            app.fityChip.Visible = 'off';
            app.fityChip.ImageSource = 'fityChip.PNG';
            app.hundoChip = uiimage(app.UIFigure);
            app.hundoChip.ImageClickedFcn = createCallbackFcn(app, @hundoChipImageClicked, true);
            app.hundoChip.Enable = 'off';
            app.hundoChip.Visible = 'off';
            app.hundoChip.ImageSource = 'hundoChip.png';
            app.twentyChip = uiimage(app.UIFigure);
            app.twentyChip.ImageClickedFcn = createCallbackFcn(app, @twentyChipImageClicked, true);
            app.twentyChip.Enable = 'off';
            app.twentyChip.Visible = 'off';
            app.twentyChip.ImageSource = 'twentyChip.png';
            app.communityCard1 = uiimage(app.UIFigure);
            app.communityCard1.Visible = 'off';
            app.communityCard1.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard2 = uiimage(app.UIFigure);
            app.communityCard2.Visible = 'off';
            app.communityCard2.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard3 = uiimage(app.UIFigure);
            app.communityCard3.Visible = 'off';
            app.communityCard3.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard4 = uiimage(app.UIFigure);
            app.communityCard4.Visible = 'off';
            app.communityCard4.ImageSource = 'FaceDownCard.jpeg';
            app.communityCard5 = uiimage(app.UIFigure);
            app.communityCard5.Visible = 'off';
            app.communityCard5.ImageSource = 'FaceDownCard.jpeg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.Visible = 'off';
            app.Image6_2.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_3 = uiimage(app.UIFigure);
            app.Image6_3.Visible = 'off';
            app.Image6_3.ImageSource = 'FaceDownCard.jpeg';
            app.Image6_4 = uiimage(app.UIFigure);
            app.Image6_4.Visible = 'off';
            app.Image6_4.ImageSource = 'FaceDownCard.jpeg';
            app.player1_Card1 = uiimage(app.UIFigure);
            app.player1_Card1.Visible = 'off';
            app.player1_Card1.ImageSource = 'FaceDownCard.jpeg';
            app.player1_Card2 = uiimage(app.UIFigure);
            app.player1_Card2.Visible = 'off';
            app.player1_Card2.ImageSource = 'FaceDownCard.jpeg';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'TitlePage.png';
            app.shuffleGIF = uiimage(app.UIFigure);
            app.shuffleGIF.Enable = 'off';
            app.shuffleGIF.Visible = 'off';
            app.shuffleGIF.ImageSource = 'deckShuffle.gif';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalPushinProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end